classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        UIAxes                        matlab.ui.control.UIAxes
        RightPanel                    matlab.ui.container.Panel
        QHighText                     matlab.ui.control.EditField
        QHighMidText                  matlab.ui.control.EditField
        QMidText                      matlab.ui.control.EditField
        QLowMidText                   matlab.ui.control.EditField
        QLowText                      matlab.ui.control.EditField
        ConnectButton                 matlab.ui.control.StateButton
        PortEditField                 matlab.ui.control.EditField
        PortEditFieldLabel            matlab.ui.control.Label
        QHighMidKnob                  matlab.ui.control.Knob
        QHighMidKnobLabel             matlab.ui.control.Label
        QLowMidKnob                   matlab.ui.control.Knob
        QLowMidKnobLabel              matlab.ui.control.Label
        GainLowMidText                matlab.ui.control.EditField
        GainLowMid200HzSlider         matlab.ui.control.Slider
        GainLowMid200HzSliderLabel    matlab.ui.control.Label
        GainHighMid3000HzSlider       matlab.ui.control.Slider
        GainHighMidLabel              matlab.ui.control.Label
        GainHighMidText               matlab.ui.control.EditField
        BandEqualizerusingSTM32Label  matlab.ui.control.Label
        EQMAT5Label                   matlab.ui.control.Label
        v030demoLabel                 matlab.ui.control.Label
        GainHighText                  matlab.ui.control.EditField
        GainMidText                   matlab.ui.control.EditField
        GainLowText                   matlab.ui.control.EditField
        QuitButton                    matlab.ui.control.Button
        ResetButton                   matlab.ui.control.Button
        GainHigh10000HzSlider         matlab.ui.control.Slider
        GainHigh10000HzSliderLabel    matlab.ui.control.Label
        GainMid1500HzSlider           matlab.ui.control.Slider
        GainMid1500HzSliderLabel      matlab.ui.control.Label
        GainLow50HzSlider             matlab.ui.control.Slider
        GainLow50HzSliderLabel        matlab.ui.control.Label
        QHighKnob                     matlab.ui.control.Knob
        QHighKnobLabel                matlab.ui.control.Label
        QMidKnob                      matlab.ui.control.Knob
        QMidKnobLabel                 matlab.ui.control.Label
        QLowKnob                      matlab.ui.control.Knob
        QLowKnobLabel                 matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        comport = "COM";
        device;
        Fc_low = 50; 
        Fc_lowmid = 200;
        Fc_mid = 1500;
        Fc_highmid = 3000;
        Fc_high = 10000;
        Fs = 96000
        numPoints;              % Define numPoints as the frequency range sampled or divided within that range
        frequencies;            % creating an array of 1000 frequencies evenly distributed on a logarithmic scale between 20 Hz and 20 kHz.
        magnitudeResponse1;
        magnitudeResponse2;
        magnitudeResponse3;
        magnitudeResponse4; %midlow
        magnitudeResponse5; %midhigh
        LowCoeff = zeros(1, 6);
        MidCoeff = zeros(1, 6);
        HighCoeff = zeros(1, 6);
        LowMidCoeff = zeros(1, 6);
        HighMidCoeff = zeros(1, 6);
    end
    
    methods (Access = private)
        
        function updateEqualizer(app)
            % Create empty array of zero
            magResponse1 = zeros(1, app.numPoints);
            magResponse2 = zeros(1, app.numPoints);
            magResponse3 = zeros(1, app.numPoints);
            magResponse4 = zeros(1, app.numPoints); %midlow
            magResponse5 = zeros(1, app.numPoints); %midhigh

            for i = 1:app.numPoints
                f = app.frequencies(i);

                % Read the slider values and assign the value into the parameters
                lowGain = app.GainLow50HzSlider.Value;
                midlowGain = app.GainLowMid200HzSlider.Value;
                midGain = app.GainMid1500HzSlider.Value;
                midhighGain = app.GainHighMid3000HzSlider.Value;
                highGain = app.GainHigh10000HzSlider.Value;
                Qlow = app.QLowKnob.Value;
                Qlowmid = app.QLowMidKnob.Value;
                Qmid = app.QMidKnob.Value;
                Qhighmid = app.QHighMidKnob.Value;
                Qhigh = app.QHighKnob.Value;  

                % Calculate filter coefficients for each band based on slider values
                % low band
                w01 = (2 * pi * app.Fc_low) / app.Fs; % omega = 2*pi*f / fs
                A1 = 10^(lowGain / 40); % gain in dB converted to linear
                alpha1 = sin(w01) / (2 * Qlow); % width (bandwidth) calculated by q factor
                b0 = 1 + alpha1 * A1; % current input sample's contribution without any delay.
                b1 = -2 * cos(w01); % represent the contributions of the past input samples after one and two time steps, respectively.
                b2 = 1 - alpha1 * A1; % represent the contributions of the past input samples after one and two time steps, respectively.
                a0 = 1 + alpha1 / A1; % current output's direct dependence on the current input and its previous values.
                a1 = -2 * cos(w01); % influence the feedback loop in the filter by specifying the influence of past output values (feedback) on the current output.
                a2 = 1 - alpha1 / A1; % influence the feedback loop in the filter by specifying the influence of past output values (feedback) on the current output.
                % mid band
                w02 = (2 * pi * app.Fc_mid) / app.Fs;
                A2 = 10^(midGain / 40);
                alpha2 = sin(w02) / (2 * Qmid);
                b0_mid = 1 + alpha2 * A2;
                b1_mid = -2 * cos(w02);
                b2_mid = 1 - alpha2 * A2;
                a0_mid = 1 + alpha2 / A2;
                a1_mid = -2 * cos(w02);
                a2_mid = 1 - alpha2 / A2;
                % high band
                w03 = (2 * pi * app.Fc_high) / app.Fs;
                A3 = 10^(highGain / 40);
                alpha3 = sin(w03) / (2 * Qhigh);
                b0_high = 1 + alpha3 * A3;
                b1_high = -2 * cos(w03);
                b2_high = 1 - alpha3 * A3;
                a0_high = 1 + alpha3 / A3;
                a1_high = -2 * cos(w03);
                a2_high = 1 - alpha3 / A3;
                % lowmid band
                w04 = (2 * pi * app.Fc_lowmid) / app.Fs;
                A4 = 10^(midlowGain / 40);
                alpha4 = sin(w04) / (2 * Qlowmid);
                b0_lowmid = 1 + alpha4 * A4;
                b1_lowmid = -2 * cos(w04);
                b2_lowmid = 1 - alpha4 * A4;
                a0_lowmid = 1 + alpha4 / A4;
                a1_lowmid = -2 * cos(w04);
                a2_lowmid = 1 - alpha4 / A4;
                % highmid band
                w05 = (2 * pi * app.Fc_highmid) / app.Fs;
                A4 = 10^(midhighGain / 40);
                alpha5 = sin(w05) / (2 * Qhighmid);
                b0_highmid = 1 + alpha5 * A4;
                b1_highmid = -2 * cos(w05);
                b2_highmid = 1 - alpha5 * A4;
                a0_highmid = 1 + alpha5 / A4;
                a1_highmid = -2 * cos(w05);
                a2_highmid = 1 - alpha5 / A4;

                % Create a z variable for the transfer function (exp(jw))
                z = exp(1i * 2 * pi * f / app.Fs);
        
                % Compute the frequency response for the first band (Z Transfer Function)
                H1 = (b0 + b1 * z + b2 * z^2) / (a0 + a1 * z + a2 * z^2);
        
                % Compute the frequency response for the Mid band (Z Transfer Function)
                H2 = (b0_mid + b1_mid * z + b2_mid * z^2) / (a0_mid + a1_mid * z + a2_mid * z^2);
        
                % Compute the frequency response for the High band (Z Transfer Function)
                H3 = (b0_high + b1_high * z + b2_high * z^2) / (a0_high + a1_high * z + a2_high * z^2);

                % Compute the frequency response for the LowMid band (Z Transfer Function)
                H4 = (b0_lowmid + b1_lowmid * z + b2_lowmid * z^2) / (a0_lowmid + a1_lowmid * z + a2_lowmid * z^2);

                % Compute the frequency response for the HighMid band (Z Transfer Function)
                H5 = (b0_highmid + b1_highmid * z + b2_highmid * z^2) / (a0_highmid + a1_highmid * z + a2_highmid * z^2);
                
                % computes the magnitude response, which is the absolute value of the complex frequency response obtained for each band at specific frequencies determined by the frequency vector.
                % shows how much the filter amplifies or attenuates signals at various frequencies.
                magMagnitude1 = abs(H1);
                magMagnitude2 = abs(H2);
                magMagnitude3 = abs(H3);
                magMagnitude4 = abs(H4);
                magMagnitude5 = abs(H5);
                
                % Store the normalized magnitude response in the array
                % populates the arrays with the magnitude responses calculated for the low, mid, and high bands at each specific frequency point.
                magResponse1(i) = magMagnitude1;
                magResponse2(i) = magMagnitude2;
                magResponse3(i) = magMagnitude3;
                magResponse4(i) = magMagnitude4;
                magResponse5(i) = magMagnitude5;
                
                % Use Biquad
                [a0, a1, a2, b1, b2] = calculateBiquadCoeffs(app, app.GainLow50HzSlider.Value, app.Fc_low, app.Fs, app.QLowKnob.Value);
                [a0_lowmid, a1_lowmid, a2_lowmid, b1_lowmid, b2_lowmid] = calculateBiquadCoeffs(app, app.GainLowMid200HzSlider.Value, app.Fc_lowmid, app.Fs, app.QLowMidKnob.Value);
                [a0_mid, a1_mid, a2_mid, b1_mid, b2_mid] = calculateBiquadCoeffs(app, app.GainMid1500HzSlider.Value, app.Fc_mid, app.Fs, app.QMidKnob.Value);
                [a0_highmid, a1_highmid, a2_highmid, b1_highmid, b2_highmid] = calculateBiquadCoeffs(app, app.GainHighMid3000HzSlider.Value, app.Fc_highmid, app.Fs, app.QHighMidKnob.Value);
                [a0_high, a1_high, a2_high, b1_high, b2_high] = calculateBiquadCoeffs(app, app.GainHigh10000HzSlider.Value, app.Fc_high, app.Fs, app.QHighKnob.Value);
                b1 = -b1;
                b1_lowmid = -b1_lowmid;
                b1_mid = -b1_mid;
                b1_highmid = -b1_highmid;
                b1_high = -b1_high;
                b2 = -b2;
                b2_lowmid = -b2_lowmid;
                b2_mid = -b2_mid;
                b2_highmid = -b2_highmid;
                b2_high = -b2_high;
                
                % Use STM32 (Phil's Lab)
                %wcT = app.Fc_low * (1 / app.Fs);
                %a0 = 4.0 + (2.0 * (app.GainLow50HzSlider.Value / app.QLowKnob.Value) * wcT) + (wcT*wcT);
                %a1 = 2.0 * (wcT*wcT) - 8.0;
                %a2 = 4.0 - (2.0 * (app.GainLow50HzSlider.Value/app.QLowKnob.Value) * wcT) + (wcT*wcT);
                %b0 = 1.0 / (4.0 + 2.0 / app.QLowKnob.Value * wcT + wcT * wcT);
                %b1 = -(2.0 * wcT * wcT - 8.0);
                %b2 = -(4.0 - 2.0 / app.QLowKnob.Value * wcT + wcT * wcT);

                % Use STM32 (EasyEQ)
                %dt = (1 / app.Fs);
                %centerFreq_rad = 2 * pi * app.Fc_low;
	            %a0 = 4 + (2 * (app.GainLow50HzSlider.Value/app.GainLow50HzSlider.Value) * centerFreq_rad * dt) + ((centerFreq_rad * dt)*(centerFreq_rad * dt));
	            %a1 = 2 * ((centerFreq_rad * dt)*(centerFreq_rad * dt)) - 8;
	            %a2 = 4 - (2 * (app.GainLow50HzSlider.Value/app.GainLow50HzSlider.Value) * centerFreq_rad * dt) + ((centerFreq_rad * dt)*(centerFreq_rad * dt));
	            %b0 = 4 + ( 2 / app.GainLow50HzSlider.Value * centerFreq_rad * dt) + ((centerFreq_rad * dt)*(centerFreq_rad * dt));
	            %b1 = -1*(2*((centerFreq_rad * dt)*(centerFreq_rad * dt))-8);
	            %b2 = -1*(4-(2/app.GainLow50HzSlider.Value*centerFreq_rad*dt) + ((centerFreq_rad * dt)*(centerFreq_rad * dt)));


                % Store Coefficient
                app.LowCoeff = [a0, a1, a2, b1, b2];
                app.MidCoeff = [a0_mid, a1_mid, a2_mid, b1_mid, b2_mid];
                app.HighCoeff = [a0_high, a1_high, a2_high, b1_high, b2_high];
                app.LowMidCoeff = [a0_lowmid, a1_lowmid, a2_lowmid, b1_lowmid, b2_lowmid];
                app.HighMidCoeff = [a0_highmid, a1_highmid, a2_highmid, b1_highmid, b2_highmid];


            end

            % Plot the magnitude responses for all three bands in app.UIAxes
            semilogx(app.UIAxes, app.frequencies, 20*log10(magResponse1), 'r', 'DisplayName', 'Low'); % a magnitude response, converted to decibels (dB) using 20*log10
            hold(app.UIAxes, 'on');
            semilogx(app.UIAxes, app.frequencies, 20*log10(magResponse2), 'g', 'DisplayName', 'Mid');
            semilogx(app.UIAxes, app.frequencies, 20*log10(magResponse3), 'b', 'DisplayName', 'High');
            semilogx(app.UIAxes, app.frequencies, 20*log10(magResponse4), 'y', 'DisplayName', 'Low-Mid');
            semilogx(app.UIAxes, app.frequencies, 20*log10(magResponse5), 'm', 'DisplayName', 'High-Mid');
            title(app.UIAxes, 'Magnitude Responses of 5-Band Parametric Equalizer');
            xlabel(app.UIAxes, 'Frequency (Hz)');
            ylabel(app.UIAxes, 'Gain (dB)');
            legend(app.UIAxes, 'Location', 'Best');
            grid(app.UIAxes, 'on');
            
            % Calculate the sum of the magnitude responses in linear scale
            % multiplies the corresponding elements at each index of the arrays together
            sumMagnitudeResponseLinear = magResponse1 .* magResponse2 .* magResponse3 .* magResponse4 .* magResponse5;
            
            % Plot the sum of the magnitude responses in black in app.UIAxes
            semilogx(app.UIAxes, app.frequencies, 20*log10(sumMagnitudeResponseLinear), 'k', 'DisplayName', 'Sum'); % sum of magnitude responses, converted to decibels (dB) using 20*log10
            xlabel(app.UIAxes, 'Frequency (Hz)');
            ylabel(app.UIAxes, 'Gain (dB)');
            legend(app.UIAxes, 'Location', 'Best');
            grid(app.UIAxes, 'on');
            
            % Set x-axis and y-axis limits and increments in app.UIAxes
            xlim(app.UIAxes, [20, 20000]); % Adjusted the lower and upper limit of the x-axis
            ylim(app.UIAxes, [-15, 15]); % Adjusted the lower and upper limit of the y-axis
            xticks(app.UIAxes, [20, 100, 1000, 20000]); % ticks on x-axis
            yticks(app.UIAxes, [-15, -10, -5, 0, 5, 10, 15]); % ticks on y-axis
            hold(app.UIAxes, 'off');
        end
        
        function SendValuesToSTM32(app, band)
            uartbuffersize = 65;
            % P.S. Why 65? Because the callback function in STM32 will trigger when the serial length is equal to the size of rx_Buffer

            % Create a string containing the slider values
            % Display the coefficients for the low band
            dataToSendLow = sprintf('Low %.6f %.6f %.6f %.6f %.6f', app.LowCoeff);  
            % Calculate padding length
            paddingLengthLow = uartbuffersize - length(dataToSendLow);
            % Create padding string with spaces
            paddingStringLow = repmat('-', 1, paddingLengthLow);
            % Pad the data string
            dataToSendPaddedLow = strcat(dataToSendLow, paddingStringLow);
            disp(dataToSendPaddedLow);

            % Display the coefficients for the low mid band
            dataToSendLowMid = sprintf('LowMid %.6f %.6f %.6f %.6f %.6f', app.LowMidCoeff);  
            % Calculate padding length
            paddingLengthLowMid = uartbuffersize - length(dataToSendLowMid);
            % Create padding string with spaces
            paddingStringLowMid = repmat('-', 1, paddingLengthLowMid);
            % Pad the data string
            dataToSendPaddedLowMid = strcat(dataToSendLowMid, paddingStringLowMid);
            disp(dataToSendPaddedLowMid);

            % Display the coefficients for the mid band
            dataToSendMid = sprintf('Mid %.6f %.6f %.6f %.6f %.6f', app.MidCoeff);
            % Calculate padding length
            paddingLengthMid = uartbuffersize - length(dataToSendMid);
            % Create padding string with spaces
            paddingStringMid = repmat('-', 1, paddingLengthMid);
            % Pad the data string   
            dataToSendPaddedMid = strcat(dataToSendMid, paddingStringMid);
            disp(dataToSendPaddedMid);
            
            % Display the coefficients for the low mid band
            dataToSendHighMid = sprintf('HighMid %.6f %.6f %.6f %.6f %.6f', app.HighMidCoeff);  
            % Calculate padding length
            paddingLengthHighMid = uartbuffersize - length(dataToSendHighMid);
            % Create padding string with spaces
            paddingStringHighMid = repmat('-', 1, paddingLengthHighMid);
            % Pad the data string
            dataToSendPaddedHighMid = strcat(dataToSendHighMid, paddingStringHighMid);
            disp(dataToSendPaddedHighMid);
            
            % Display the coefficients for the high band
            dataToSendHigh = sprintf('High %.6f %.6f %.6f %.6f %.6f', app.HighCoeff);
            % Calculate padding length
            paddingLengthHigh = uartbuffersize - length(dataToSendHigh);
            % Create padding string with spaces
            paddingStringHigh = repmat('-', 1, paddingLengthHigh);
            % Pad the data string
            dataToSendPaddedHigh = strcat(dataToSendHigh, paddingStringHigh);
            disp(dataToSendPaddedHigh);
            
            try
                % Check if the serial port is valid before sending data
                if isvalid(app.device) && strcmp(app.device.Status, 'open')
                    % Send the data
                    if strcmp(band, 'low')
                        write(app.device, dataToSendPaddedLow, "string");
                        disp(dataToSendLow);
                    elseif strcmp(band, 'lowmid')
                        write(app.device, dataToSendPaddedLowMid, "string");
                        disp(dataToSendLowMid);
                    elseif strcmp(band, 'mid')
                        write(app.device, dataToSendPaddedMid, "string");
                        disp(dataToSendMid);
                    elseif strcmp(band, 'highmid')
                        write(app.device, dataToSendPaddedHighMid, "string");
                        disp(dataToSendHighMid);
                    elseif strcmp(band, 'high')
                        write(app.device, dataToSendPaddedHigh, "string");
                        disp(dataToSendHigh);
                    elseif strcmp(band, 'reset')
                        write(app.device, "Reset------------------------------------------------------------", "string"); % Fix length to 65 (Same as uartbuffersize)
                        disp("Reset!")
                        disp(dataToSendLow);
                        disp(dataToSendLowMid);
                        disp(dataToSendMid);
                        disp(dataToSendHighMid);
                        disp(dataToSendHigh);
                    else
                        disp('Error: Invalid band selected.');
                        uialert(app.UIFigure,"Invalid band selected.","Error occurred in MATLAB code");
                    end
                else
                    disp('Error: Serial port is not valid.');
                    uialert(app.UIFigure,"Serial port is not valid.","Can't connect to STM32");
                end
            catch ME
                disp('Error sending data to STM32.');
                disp(ME.message); % Display the actual error message
                uialert(app.UIFigure,"Can't send data to STM32.","Can't connect to STM32");
            end
        end
        
        function [a0, a1, a2, b1, b2] = calculateBiquadCoeffs(app, gain, Fc, Fs, Q)
            % This function calculates the Biquad filter coefficients
            % gain: the gain value in dB
            % Fc: center frequency
            % Fs: sampling frequency
            % Q: quality factor
        
            % Calculate V and K
            V = 10^(abs(gain) / 20);
            K = tan(pi * Fc / Fs);
            
            if gain >= 0
                norm = 1 / (1 + (1/Q) * K + K * K);
                a0 = (1 + (V/Q) * K + K * K) * norm;
                a1 = 2 * (K * K - 1) * norm;
                a2 = (1 - (V/Q) * K + K * K) * norm;
                b1 = a1;
                b2 = (1 - (1/Q) * K + K * K) * norm;
            else
                norm = 1 / (1 + (V/Q) * K + K * K);
                a0 = (1 + (1/Q) * K + K * K) * norm;
                a1 = 2 * (K * K - 1) * norm;
                a2 = (1 - (1/Q) * K + K * K) * norm;
                b1 = a1;
                b2 = (1 - (V/Q) * K + K * K) * norm;
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.numPoints = 1000; % Set the number of points to a valid value
            app.frequencies = logspace(log10(20), log10(20000), app.numPoints); % Initialize frequencies

            % Initialize magnitudeResponse properties with zeros
            app.magnitudeResponse1 = zeros(1, app.numPoints);
            app.magnitudeResponse2 = zeros(1, app.numPoints);
            app.magnitudeResponse3 = zeros(1, app.numPoints);
            app.magnitudeResponse4 = zeros(1, app.numPoints);
            app.magnitudeResponse5 = zeros(1, app.numPoints);

            updateEqualizer(app); % Call the updateEqualizer function
        end

        % Value changing function: QLowKnob
        function QLowKnobValueChanging(app, event)
            changingValue = event.Value;
            % app.GainLowText.Value = app.GainLowText.Value + string(changingValue) + ' ';
            disp('Q Low : ' + string(changingValue)); 
            app.QLowText.Value = string(changingValue);
        end

        % Value changing function: QMidKnob
        function QMidKnobValueChanging(app, event)
            changingValue = event.Value;
            disp('Q Mid : ' + string(changingValue));
            app.QMidText.Value = string(changingValue);
        end

        % Value changing function: QHighKnob
        function QHighKnobValueChanging(app, event)
            changingValue = event.Value;
            disp('Q High : ' + string(changingValue));
            app.QHighText.Value = string(changingValue);
        end

        % Value changing function: GainLow50HzSlider
        function GainLow50HzSliderValueChanging(app, event)
            changingValue = event.Value;
            disp('Gain Low : ' + string(changingValue));
            app.GainLowText.Value = string(changingValue);
        end

        % Value changing function: GainMid1500HzSlider
        function GainMid1500HzSliderValueChanging(app, event)
            changingValue = event.Value;
            disp('Gain Mid : ' + string(changingValue));
            app.GainMidText.Value = string(changingValue);
        end

        % Value changing function: GainHigh10000HzSlider
        function GainHigh10000HzSliderValueChanging(app, event)
            changingValue = event.Value;
            disp('Gain High : ' + string(changingValue));
            app.GainHighText.Value = string(changingValue);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.QLowKnob.Value = 1.0;
            app.QMidKnob.Value = 1.0;
            app.QHighKnob.Value = 1.0;
            app.QLowMidKnob.Value = 1.0;
            app.QHighMidKnob.Value = 1.0;
            app.GainLow50HzSlider.Value = 0.0;
            app.GainMid1500HzSlider.Value = 0.0;
            app.GainHigh10000HzSlider.Value = 0.0;
            app.GainLowMid200HzSlider.Value = 0.0;
            app.GainHighMid3000HzSlider.Value = 0.0;
            app.GainLowText.Value = "0.0";
            app.GainMidText.Value = "0.0";
            app.GainHighText.Value = "0.0";
            app.GainLowMidText.Value = "0.0";
            app.GainHighMidText.Value = "0.0";
            app.QHighText.Value = "1.0";
            app.QHighMidText.Value = "1.0";
            app.QMidText.Value = "1.0";
            app.QLowMidText.Value = "1.0";
            app.QLowText.Value = "1.0";
          
    
            % Update the equalizer based on the new values
            updateEqualizer(app);
            SendValuesToSTM32(app, 'reset');
        end

        % Value changed function: QLowKnob
        function QLowKnobValueChanged(app, event)
            value = app.QLowKnob.Value;
            updateEqualizer(app);
            SendValuesToSTM32(app, 'low');
        end

        % Value changed function: QMidKnob
        function QMidKnobValueChanged(app, event)
            value = app.QMidKnob.Value;
            updateEqualizer(app);
            SendValuesToSTM32(app, 'mid');
        end

        % Value changed function: QHighKnob
        function QHighKnobValueChanged(app, event)
            value = app.QHighKnob.Value;
            updateEqualizer(app);
            SendValuesToSTM32(app, 'high');
        end

        % Value changed function: GainLow50HzSlider
        function GainLow50HzSliderValueChanged(app, event)
            value = app.GainLow50HzSlider.Value;
            updateEqualizer(app);
            SendValuesToSTM32(app, 'low');
        end

        % Value changed function: GainMid1500HzSlider
        function GainMid1500HzSliderValueChanged(app, event)
            value = app.GainMid1500HzSlider.Value;
            updateEqualizer(app);
            SendValuesToSTM32(app, 'mid');
        end

        % Value changed function: GainHigh10000HzSlider
        function GainHigh10000HzSliderValueChanged(app, event)
            value = app.GainHigh10000HzSlider.Value;
            updateEqualizer(app);
            SendValuesToSTM32(app, 'high');
        end

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
            % Close the main figure to terminate the app
            try

                if isvalid(app.device) && strcmp(app.device.Status, 'open')                 % Undefined function 'isvalid' for input arguments of type 'double'.
                    delete(app.device); % Close and delete the serial port object
                    disp('Serial port closed successfully.');
                else
                    disp('Serial port is not open or is invalid.');
                end
                delete(app.UIFigure);
            catch
                delete(app.UIFigure); % STM32 is not already connected.
            end
        end

        % Value changed function: GainLowText
        function GainLowTextValueChanged(app, event)
            value = app.GainLowText.Value;
            app.GainLow50HzSlider.Value = str2double(value);
            updateEqualizer(app);
            SendValuesToSTM32(app, 'low');
        end

        % Value changed function: GainMidText
        function GainMidTextValueChanged(app, event)
            value = app.GainMidText.Value;
            app.GainMid1500HzSlider.Value = str2double(value);
            updateEqualizer(app);
            SendValuesToSTM32(app, 'mid');
        end

        % Value changed function: GainHighText
        function GainHighTextValueChanged(app, event)
            value = app.GainHighText.Value;
            app.GainHigh10000HzSlider.Value = str2double(value);
            updateEqualizer(app);
            SendValuesToSTM32(app, 'high');
        end

        % Value changed function: GainLowMidText
        function GainLowMidTextValueChanged(app, event)
            value = app.GainLowMidText.Value;
            app.GainLowMid200HzSlider.Value = str2double(value);
            updateEqualizer(app);
            SendValuesToSTM32(app, 'lowmid');
        end

        % Value changed function: GainHighMidText
        function GainHighMidTextValueChanged(app, event)
            value = app.GainHighMidText.Value;
            app.GainHighMid3000HzSlider.Value = str2double(value);
            updateEqualizer(app);
            SendValuesToSTM32(app, 'highmid');
        end

        % Value changing function: GainLowMid200HzSlider
        function GainLowMid200HzSliderValueChanging(app, event)
            changingValue = event.Value;
            disp('Gain Low-Mid : ' + string(changingValue));
            app.GainLowMidText.Value = string(changingValue);
        end

        % Value changing function: GainHighMid3000HzSlider
        function GainHighMid3000HzSliderValueChanging(app, event)
            changingValue = event.Value;
            disp('Gain High-Mid : ' + string(changingValue));
            app.GainHighMidText.Value = string(changingValue);
        end

        % Value changed function: GainLowMid200HzSlider
        function GainLowMid200HzSliderValueChanged(app, event)
            value = app.GainLowMid200HzSlider.Value;
            updateEqualizer(app);
            SendValuesToSTM32(app, 'lowmid');
        end

        % Value changed function: GainHighMid3000HzSlider
        function GainHighMid3000HzSliderValueChanged(app, event)
            value = app.GainHighMid3000HzSlider.Value;
            updateEqualizer(app);
            SendValuesToSTM32(app, 'highmid');
        end

        % Value changed function: QLowMidKnob
        function QLowMidKnobValueChanged(app, event)
            value = app.QLowMidKnob.Value;
            updateEqualizer(app);
            SendValuesToSTM32(app, 'lowmid');
        end

        % Value changing function: QLowMidKnob
        function QLowMidKnobValueChanging(app, event)
            changingValue = event.Value;
            disp('Q Mid-Low : ' + string(changingValue));
            app.QLowMidText.Value = string(changingValue);
        end

        % Value changing function: QHighMidKnob
        function QHighMidKnobValueChanging(app, event)
            changingValue = event.Value;
            disp('Q Mid-High : ' + string(changingValue));
            app.QHighMidText.Value = string(changingValue);
        end

        % Value changed function: QHighMidKnob
        function QHighMidKnobValueChanged(app, event)
            value = app.QHighMidKnob.Value;
            updateEqualizer(app);
            SendValuesToSTM32(app, 'highmid');
        end

        % Value changed function: PortEditField
        function PortEditFieldValueChanged(app, event)
            value = app.PortEditField.Value;
            app.comport = value;
        end

        % Callback function
        function ConnectButtonPushed(app, event)

        end

        % Value changed function: ConnectButton
        function ConnectButtonValueChanged(app, event)
            value = app.ConnectButton.Value;
            if strlength(string(app.comport)) > 3
                if value == 1
                    try
                        app.device = serialport(app.comport,115200);
                        disp('Serial port opened successfully.');
                        app.ConnectButton.BackgroundColor = "0.18,0.87,0.23";
                        app.PortEditField.Editable = "off";
                        app.ConnectButton.Text = "Disconnect";
                        SendValuesToSTM32(app, 'reset');
                        app.ConnectButton.Tooltip = "Click to Disconnect";
                    catch 
                        alerttext = "Can't open port " + app.comport;
                        uialert(app.UIFigure,alerttext,"Can't connect to STM32");
                        app.ConnectButton.Value = 0;
                    end
                else
                    delete(app.device); % Close and delete the serial port object
                    disp('Serial port closed successfully.');
                    app.ConnectButton.BackgroundColor = "1.00,0.36,0.36";
                    app.PortEditField.Editable = "on";
                    app.ConnectButton.Text = "Connect";
                    app.ConnectButton.Tooltip = "Click to connect";
                end
            else
                disp('Type COM port number first!');
                app.ConnectButton.Value = 0;
                uialert(app.UIFigure,"Type COM port number first! (e.g. COM3)","Can't connect to STM32");
            end
        end

        % Value changed function: QLowText
        function QLowTextValueChanged(app, event)
            value = app.QLowText.Value;
            app.QLowKnob.Value = str2double(value);
            updateEqualizer(app);
            SendValuesToSTM32(app, 'low');
            
        end

        % Value changed function: QLowMidText
        function QLowMidTextValueChanged(app, event)
            value = app.QLowMidText.Value;
            app.QLowMidKnob.Value = str2double(value);
            updateEqualizer(app);
            SendValuesToSTM32(app, 'lowmid');
        end

        % Value changed function: QMidText
        function QMidTextValueChanged(app, event)
            value = app.QMidText.Value;
            app.QMidKnob.Value = str2double(value);
            updateEqualizer(app);
            SendValuesToSTM32(app, 'mid');
        end

        % Value changed function: QHighMidText
        function QHighMidTextValueChanged(app, event)
            value = app.QHighMidText.Value;
            app.QHighMidKnob.Value = str2double(value);
            updateEqualizer(app);
            SendValuesToSTM32(app, 'highmid');
        end

        % Value changed function: QHighText
        function QHighTextValueChanged(app, event)
            value = app.QHighText.Value;
            app.QHighKnob.Value = str2double(value);
            updateEqualizer(app);
            SendValuesToSTM32(app, 'high');
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {422, 422};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {565, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.LeftPanel);
            title(app.UIAxes, 'Bode Plot')
            xlabel(app.UIAxes, 'Frequency (Hz)')
            ylabel(app.UIAxes, 'Magnitude (dB)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 10000];
            app.UIAxes.XScale = 'log';
            app.RightPanel = uipanel(app.GridLayout);
            app.QLowKnobLabel = uilabel(app.RightPanel);
            app.QLowKnobLabel.Text = 'Q Low';
            app.QLowKnob = uiknob(app.RightPanel, 'continuous');
            app.QLowKnob.Limits = [0.1 10];
            app.QLowKnob.ValueChangedFcn = createCallbackFcn(app, @QLowKnobValueChanged, true);
            app.QLowKnob.ValueChangingFcn = createCallbackFcn(app, @QLowKnobValueChanging, true);
            app.QLowKnob.Value = 1;
            app.QMidKnobLabel = uilabel(app.RightPanel);
            app.QMidKnobLabel.Text = 'Q Mid';
            app.QMidKnob = uiknob(app.RightPanel, 'continuous');
            app.QMidKnob.Limits = [0.1 10];
            app.QMidKnob.ValueChangedFcn = createCallbackFcn(app, @QMidKnobValueChanged, true);
            app.QMidKnob.ValueChangingFcn = createCallbackFcn(app, @QMidKnobValueChanging, true);
            app.QMidKnob.Value = 1;
            app.QHighKnobLabel = uilabel(app.RightPanel);
            app.QHighKnobLabel.Text = 'Q High';
            app.QHighKnob = uiknob(app.RightPanel, 'continuous');
            app.QHighKnob.Limits = [0.1 10];
            app.QHighKnob.ValueChangedFcn = createCallbackFcn(app, @QHighKnobValueChanged, true);
            app.QHighKnob.ValueChangingFcn = createCallbackFcn(app, @QHighKnobValueChanging, true);
            app.QHighKnob.Value = 1;
            app.GainLow50HzSliderLabel = uilabel(app.RightPanel);
            app.GainLow50HzSliderLabel.Text = {'Gain Low'; '50Hz'};
            app.GainLow50HzSlider = uislider(app.RightPanel);
            app.GainLow50HzSlider.Limits = [-40 40];
            app.GainLow50HzSlider.ValueChangedFcn = createCallbackFcn(app, @GainLow50HzSliderValueChanged, true);
            app.GainLow50HzSlider.ValueChangingFcn = createCallbackFcn(app, @GainLow50HzSliderValueChanging, true);
            app.GainMid1500HzSliderLabel = uilabel(app.RightPanel);
            app.GainMid1500HzSliderLabel.Text = {'Gain Mid'; '1500Hz'};
            app.GainMid1500HzSlider = uislider(app.RightPanel);
            app.GainMid1500HzSlider.Limits = [-40 40];
            app.GainMid1500HzSlider.ValueChangedFcn = createCallbackFcn(app, @GainMid1500HzSliderValueChanged, true);
            app.GainMid1500HzSlider.ValueChangingFcn = createCallbackFcn(app, @GainMid1500HzSliderValueChanging, true);
            app.GainHigh10000HzSliderLabel = uilabel(app.RightPanel);
            app.GainHigh10000HzSliderLabel.Text = {'Gain High'; '10000Hz'};
            app.GainHigh10000HzSlider = uislider(app.RightPanel);
            app.GainHigh10000HzSlider.Limits = [-40 40];
            app.GainHigh10000HzSlider.ValueChangedFcn = createCallbackFcn(app, @GainHigh10000HzSliderValueChanged, true);
            app.GainHigh10000HzSlider.ValueChangingFcn = createCallbackFcn(app, @GainHigh10000HzSliderValueChanging, true);
            app.ResetButton = uibutton(app.RightPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Tooltip = {'Click to reset the config'};
            app.ResetButton.Text = 'Reset';
            app.QuitButton = uibutton(app.RightPanel, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Tooltip = {'Click to quit the app'};
            app.QuitButton.Text = 'Quit';
            app.GainLowText = uieditfield(app.RightPanel, 'text');
            app.GainLowText.ValueChangedFcn = createCallbackFcn(app, @GainLowTextValueChanged, true);
            app.GainLowText.Value = '0.0';
            app.GainMidText = uieditfield(app.RightPanel, 'text');
            app.GainMidText.ValueChangedFcn = createCallbackFcn(app, @GainMidTextValueChanged, true);
            app.GainMidText.Value = '0.0';
            app.GainHighText = uieditfield(app.RightPanel, 'text');
            app.GainHighText.ValueChangedFcn = createCallbackFcn(app, @GainHighTextValueChanged, true);
            app.GainHighText.Value = '0.0';
            app.v030demoLabel = uilabel(app.RightPanel);
            app.v030demoLabel.Text = 'v0.3.0 demo';
            app.EQMAT5Label = uilabel(app.RightPanel);
            app.EQMAT5Label.Text = 'EQMAT5';
            app.BandEqualizerusingSTM32Label = uilabel(app.RightPanel);
            app.BandEqualizerusingSTM32Label.Text = {'5-Band Equalizer'; 'using STM32'};
            app.GainHighMidText = uieditfield(app.RightPanel, 'text');
            app.GainHighMidText.ValueChangedFcn = createCallbackFcn(app, @GainHighMidTextValueChanged, true);
            app.GainHighMidText.Value = '0.0';
            app.GainHighMidLabel = uilabel(app.RightPanel);
            app.GainHighMidLabel.Text = {'Gain High-Mid'; '3000Hz'};
            app.GainHighMid3000HzSlider = uislider(app.RightPanel);
            app.GainHighMid3000HzSlider.Limits = [-40 40];
            app.GainHighMid3000HzSlider.ValueChangedFcn = createCallbackFcn(app, @GainHighMid3000HzSliderValueChanged, true);
            app.GainHighMid3000HzSlider.ValueChangingFcn = createCallbackFcn(app, @GainHighMid3000HzSliderValueChanging, true);
            app.GainLowMid200HzSliderLabel = uilabel(app.RightPanel);
            app.GainLowMid200HzSliderLabel.Text = {'Gain Low-Mid'; '200Hz'};
            app.GainLowMid200HzSlider = uislider(app.RightPanel);
            app.GainLowMid200HzSlider.Limits = [-40 40];
            app.GainLowMid200HzSlider.ValueChangedFcn = createCallbackFcn(app, @GainLowMid200HzSliderValueChanged, true);
            app.GainLowMid200HzSlider.ValueChangingFcn = createCallbackFcn(app, @GainLowMid200HzSliderValueChanging, true);
            app.GainLowMidText = uieditfield(app.RightPanel, 'text');
            app.GainLowMidText.ValueChangedFcn = createCallbackFcn(app, @GainLowMidTextValueChanged, true);
            app.GainLowMidText.Value = '0.0';
            app.QLowMidKnobLabel = uilabel(app.RightPanel);
            app.QLowMidKnobLabel.Text = 'Q Low-Mid';
            app.QLowMidKnob = uiknob(app.RightPanel, 'continuous');
            app.QLowMidKnob.Limits = [0.1 10];
            app.QLowMidKnob.ValueChangedFcn = createCallbackFcn(app, @QLowMidKnobValueChanged, true);
            app.QLowMidKnob.ValueChangingFcn = createCallbackFcn(app, @QLowMidKnobValueChanging, true);
            app.QLowMidKnob.Value = 1;
            app.QHighMidKnobLabel = uilabel(app.RightPanel);
            app.QHighMidKnobLabel.Text = 'Q High-Mid';
            app.QHighMidKnob = uiknob(app.RightPanel, 'continuous');
            app.QHighMidKnob.Limits = [0.1 10];
            app.QHighMidKnob.ValueChangedFcn = createCallbackFcn(app, @QHighMidKnobValueChanged, true);
            app.QHighMidKnob.ValueChangingFcn = createCallbackFcn(app, @QHighMidKnobValueChanging, true);
            app.QHighMidKnob.Value = 1;
            app.PortEditFieldLabel = uilabel(app.RightPanel);
            app.PortEditFieldLabel.Text = 'Port';
            app.PortEditField = uieditfield(app.RightPanel, 'text');
            app.PortEditField.CharacterLimits = [0 5];
            app.PortEditField.ValueChangedFcn = createCallbackFcn(app, @PortEditFieldValueChanged, true);
            app.PortEditField.Tooltip = {'Type the port number'};
            app.PortEditField.Placeholder = 'COM';
            app.PortEditField.Value = 'COM';
            app.ConnectButton = uibutton(app.RightPanel, 'state');
            app.ConnectButton.ValueChangedFcn = createCallbackFcn(app, @ConnectButtonValueChanged, true);
            app.ConnectButton.Tooltip = {'Click to connect'};
            app.ConnectButton.Text = 'Connect';
            app.QLowText = uieditfield(app.RightPanel, 'text');
            app.QLowText.ValueChangedFcn = createCallbackFcn(app, @QLowTextValueChanged, true);
            app.QLowText.Value = '1.0';
            app.QLowMidText = uieditfield(app.RightPanel, 'text');
            app.QLowMidText.ValueChangedFcn = createCallbackFcn(app, @QLowMidTextValueChanged, true);
            app.QLowMidText.Value = '1.0';
            app.QMidText = uieditfield(app.RightPanel, 'text');
            app.QMidText.ValueChangedFcn = createCallbackFcn(app, @QMidTextValueChanged, true);
            app.QMidText.Value = '1.0';
            app.QHighMidText = uieditfield(app.RightPanel, 'text');
            app.QHighMidText.ValueChangedFcn = createCallbackFcn(app, @QHighMidTextValueChanged, true);
            app.QHighMidText.Value = '1.0';
            app.QHighText = uieditfield(app.RightPanel, 'text');
            app.QHighText.ValueChangedFcn = createCallbackFcn(app, @QHighTextValueChanged, true);
            app.QHighText.Value = '1.0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end