classdef testgui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        BandEqualizerusingSTM32Label  matlab.ui.control.Label
        EQMAT5Label                   matlab.ui.control.Label
        LowMidHighMidarenotincludedinthisdemoLabel  matlab.ui.control.Label
        GainHighText                  matlab.ui.control.EditField
        GainMidText                   matlab.ui.control.EditField
        GainLowText                   matlab.ui.control.EditField
        QuitButton                    matlab.ui.control.Button
        ResetButton                   matlab.ui.control.Button
        GainHighSlider                matlab.ui.control.Slider
        GainHighSliderLabel           matlab.ui.control.Label
        GainMidSlider                 matlab.ui.control.Slider
        GainMidSliderLabel            matlab.ui.control.Label
        GainLowSlider                 matlab.ui.control.Slider
        GainLowSliderLabel            matlab.ui.control.Label
        QHighKnob                     matlab.ui.control.Knob
        QHighKnobLabel                matlab.ui.control.Label
        QMidKnob                      matlab.ui.control.Knob
        QMidKnobLabel                 matlab.ui.control.Label
        QLowKnob                      matlab.ui.control.Knob
        QLowKnobLabel                 matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Fc_low = 50; 
        %Fc_lowmid = 200;
        Fc_mid = 1500;
        %Fc_highmid = 3000;
        Fc_high = 10000;
        Fs = 96000
        numPoints;              % Define numPoints as the frequency range sampled or divided within that range
        frequencies;            % creating an array of 1000 frequencies evenly distributed on a logarithmic scale between 20 Hz and 20 kHz.
        magnitudeResponse1;
        magnitudeResponse2;
        magnitudeResponse3;
        device = serialport("COM3",115200);
        LowCoeff = zeros(1, 6);
        MidCoeff = zeros(1, 6);
        HighCoeff = zeros(1, 6);
        LowCoeff_default = "Low 1.015447f -1.999989f 0.984553f 1.017332f -1.999989f 0.982668f"
        MidCoeff_default = "Mid 1.462671f -1.990369f 0.537329f 1.519125f -1.990369f 0.480875f"
        HighCoeff_default = "High 3.873539f -1.586707f -1.873539f 4.224164f -1.586707f -2.224164f"
    end
    
    methods (Access = private)
        
        function updateEqualizer(app)
            % Create empty array of zero
            magResponse1 = zeros(1, app.numPoints);
            magResponse2 = zeros(1, app.numPoints);
            magResponse3 = zeros(1, app.numPoints);

            for i = 1:app.numPoints
                f = app.frequencies(i);

                % Read the slider values and assign the value into the parameters
                lowGain = app.GainLowSlider.Value;
                midGain = app.GainMidSlider.Value;
                highGain = app.GainHighSlider.Value;
                Qlow = app.QLowKnob.Value;   
                Qmid = app.QMidKnob.Value;  
                Qhigh = app.QHighKnob.Value;  

                % Calculate filter coefficients for each band based on slider values
                % low band
                w01 = (2 * pi * app.Fc_low) / app.Fs; % omega = 2*pi*f / fs
                A1 = 10^(lowGain / 40); % gain in dB converted to linear
                alpha1 = sin(w01) / (2 * Qlow); % width (bandwidth) calculated by q factor
                b0 = 1 + alpha1 * A1; % current input sample's contribution without any delay.
                b1 = -2 * cos(w01); % represent the contributions of the past input samples after one and two time steps, respectively.
                b2 = 1 - alpha1 * A1; % represent the contributions of the past input samples after one and two time steps, respectively.
                a0 = 1 + alpha1 / A1; % current output's direct dependence on the current input and its previous values.
                a1 = -2 * cos(w01); % influence the feedback loop in the filter by specifying the influence of past output values (feedback) on the current output.
                a2 = 1 - alpha1 / A1; % influence the feedback loop in the filter by specifying the influence of past output values (feedback) on the current output.
                % mid band
                w02 = (2 * pi * app.Fc_mid) / app.Fs;
                A2 = 10^(midGain / 40);
                alpha2 = sin(w02) / (2 * Qmid);
                b0_mid = 1 + alpha2 * A2;
                b1_mid = -2 * cos(w02);
                b2_mid = 1 - alpha2 * A2;
                a0_mid = 1 + alpha2 / A2;
                a1_mid = -2 * cos(w02);
                a2_mid = 1 - alpha2 / A2;
                % high band
                w03 = (2 * pi * app.Fc_high) / app.Fs;
                A3 = 10^(highGain / 40);
                alpha3 = sin(w03) / (2 * Qhigh);
                b0_high = 1 + alpha3 * A3;
                b1_high = -2 * cos(w03);
                b2_high = 1 - alpha3 * A3;
                a0_high = 1 + alpha3 / A3;
                a1_high = -2 * cos(w03);
                a2_high = 1 - alpha3 / A3;
            
                % Create a z variable for the transfer function (exp(jw))
                z = exp(1i * 2 * pi * f / app.Fs);
        
                % Compute the frequency response for the first band (Z Transfer Function)
                H1 = (b0 + b1 * z + b2 * z^2) / (a0 + a1 * z + a2 * z^2);
        
                % Compute the frequency response for the Mid band (Z Transfer Function)
                H2 = (b0_mid + b1_mid * z + b2_mid * z^2) / (a0_mid + a1_mid * z + a2_mid * z^2);
        
                % Compute the frequency response for the High band (Z Transfer Function)
                H3 = (b0_high + b1_high * z + b2_high * z^2) / (a0_high + a1_high * z + a2_high * z^2);
                
                % computes the magnitude response, which is the absolute value of the complex frequency response obtained for each band at specific frequencies determined by the frequency vector.
                % shows how much the filter amplifies or attenuates signals at various frequencies.
                magMagnitude1 = abs(H1);
                magMagnitude2 = abs(H2);
                magMagnitude3 = abs(H3);
                
                % Store the normalized magnitude response in the array
                % populates the arrays with the magnitude responses calculated for the low, mid, and high bands at each specific frequency point.
                magResponse1(i) = magMagnitude1;
                magResponse2(i) = magMagnitude2;
                magResponse3(i) = magMagnitude3;
                
                % Use Biquad
                %V = 10^(abs(app.GainLowSlider.Value) / 20);
                %K = tan(pi * app.Fc_low / app.Fs);
                %Q = app.QLowKnob.Value;

                %if (app.GainLowSlider.Value >= 0)
                %norm = 1 / (1 + 1/Q * K + K * K);
                %a0 = (1 + V/Q * K + K * K) * norm;
                %a1 = 2 * (K * K - 1) * norm;
                %a2 = (1 - V/Q * K + K * K) * norm;
                %b1 = a1;
                %b2 = (1 - 1/Q * K + K * K) * norm;

                %else
                %norm = 1 / (1 + V/Q * K + K * K);
                %a0 = (1 + 1/Q * K + K * K) * norm;
                %a1 = 2 * (K * K - 1) * norm;
                %a2 = (1 - 1/Q * K + K * K) * norm;
                %b1 = a1;
                %b2 = (1 - V/Q * K + K * K) * norm;
                %end

                % Use STM32 (Phil's Lab)
                %wcT = app.Fc_low * (1 / app.Fs);
                %a0 = 4.0 + (2.0 * (app.GainLowSlider.Value / app.QLowKnob.Value) * wcT) + (wcT*wcT);
                %a1 = 2.0 * (wcT*wcT) - 8.0;
                %a2 = 4.0 - (2.0 * (app.GainLowSlider.Value/app.QLowKnob.Value) * wcT) + (wcT*wcT);
                %b0 = 1.0 / (4.0 + 2.0 / app.QLowKnob.Value * wcT + wcT * wcT);
                %b1 = -(2.0 * wcT * wcT - 8.0);
                %b2 = -(4.0 - 2.0 / app.QLowKnob.Value * wcT + wcT * wcT);

                % Use STM32 (EasyEQ)
                %dt = (1 / app.Fs);
                %centerFreq_rad = 2 * pi * app.Fc_low;
	            %a0 = 4 + (2 * (app.GainLowSlider.Value/app.GainLowSlider.Value) * centerFreq_rad * dt) + ((centerFreq_rad * dt)*(centerFreq_rad * dt));
	            %a1 = 2 * ((centerFreq_rad * dt)*(centerFreq_rad * dt)) - 8;
	            %a2 = 4 - (2 * (app.GainLowSlider.Value/app.GainLowSlider.Value) * centerFreq_rad * dt) + ((centerFreq_rad * dt)*(centerFreq_rad * dt));
	            %b0 = 4 + ( 2 / app.GainLowSlider.Value * centerFreq_rad * dt) + ((centerFreq_rad * dt)*(centerFreq_rad * dt));
	            %b1 = -1*(2*((centerFreq_rad * dt)*(centerFreq_rad * dt))-8);
	            %b2 = -1*(4-(2/app.GainLowSlider.Value*centerFreq_rad*dt) + ((centerFreq_rad * dt)*(centerFreq_rad * dt)));


                % Store Coefficient
                app.LowCoeff = [a0, a1, a2, b0, b1, b2];
                app.MidCoeff = [a0_mid, a1_mid, a2_mid, b0_mid, b1_mid, b2_mid];
                app.HighCoeff = [a0_high, a1_high, a2_high, b0_high, b1_high, b2_high];


            end

            % Plot the magnitude responses for all three bands in app.UIAxes
            semilogx(app.UIAxes, app.frequencies, 20*log10(magResponse1), 'r', 'DisplayName', 'Low'); % a magnitude response, converted to decibels (dB) using 20*log10
            hold(app.UIAxes, 'on');
            semilogx(app.UIAxes, app.frequencies, 20*log10(magResponse2), 'g', 'DisplayName', 'Mid');
            semilogx(app.UIAxes, app.frequencies, 20*log10(magResponse3), 'b', 'DisplayName', 'High');
            title(app.UIAxes, 'A copy of Magnitude Responses of 3-Band Parametric Equalizer');
            xlabel(app.UIAxes, 'Frequency (Hz)');
            ylabel(app.UIAxes, 'Gain (dB)');
            legend(app.UIAxes, 'Location', 'Best');
            grid(app.UIAxes, 'on');
            
            % Calculate the sum of the magnitude responses in linear scale
            % multiplies the corresponding elements at each index of the arrays together
            sumMagnitudeResponseLinear = magResponse1 .* magResponse2 .* magResponse3;
            
            % Plot the sum of the magnitude responses in black in app.UIAxes
            semilogx(app.UIAxes, app.frequencies, 20*log10(sumMagnitudeResponseLinear), 'k', 'DisplayName', 'Sum'); % sum of magnitude responses, converted to decibels (dB) using 20*log10
            xlabel(app.UIAxes, 'Frequency (Hz)');
            ylabel(app.UIAxes, 'Gain (dB)');
            legend(app.UIAxes, 'Location', 'Best');
            grid(app.UIAxes, 'on');
            
            % Set x-axis and y-axis limits and increments in app.UIAxes
            xlim(app.UIAxes, [20, 20000]); % Adjusted the lower and upper limit of the x-axis
            ylim(app.UIAxes, [-15, 15]); % Adjusted the lower and upper limit of the y-axis
            xticks(app.UIAxes, [20, 100, 1000, 20000]); % ticks on x-axis
            yticks(app.UIAxes, [-15, -10, -5, 0, 5, 10, 15]); % ticks on y-axis
            hold(app.UIAxes, 'off');
        end
        
        function SendValuesToSTM32(app, band)
            % P.S. Why 70? Because the callback function in STM32 will trigger when the serial length is equal to the size of rx_Buffer

            % Create a string containing the slider values
            % Display the coefficients for the low band
            dataToSendLow = sprintf('Low %.6ff %.6ff %.6ff %.6ff %.6ff %.6ff', app.LowCoeff);  
            % Calculate padding length
            paddingLengthLow = 75 - length(dataToSendLow);
            % Create padding string with spaces
            paddingStringLow = repmat('-', 1, paddingLengthLow);
            % Pad the data string
            dataToSendPaddedLow = strcat(dataToSendLow, paddingStringLow);
            disp(dataToSendPaddedLow);

            % Display the coefficients for the mid band
            dataToSendMid = sprintf('Mid %.6ff %.6ff %.6ff %.6ff %.6ff %.6ff', app.MidCoeff);
            % Calculate padding length
            paddingLengthMid = 75 - length(dataToSendMid);
            % Create padding string with spaces
            paddingStringMid = repmat('-', 1, paddingLengthMid);
            % Pad the data string   
            dataToSendPaddedMid = strcat(dataToSendMid, paddingStringMid);
            disp(dataToSendPaddedMid);
            
            % Display the coefficients for the high band
            dataToSendHigh = sprintf('High %.6ff %.6ff %.6ff %.6ff %.6ff %.6ff', app.HighCoeff);
            % Calculate padding length
            paddingLengthHigh = 75 - length(dataToSendHigh);
            % Create padding string with spaces
            paddingStringHigh = repmat('-', 1, paddingLengthHigh);
            % Pad the data string
            dataToSendPaddedHigh = strcat(dataToSendHigh, paddingStringHigh);
            disp(dataToSendPaddedHigh);
            
            try
                % Check if the serial port is valid before sending data
                if isvalid(app.device) && strcmp(app.device.Status, 'open')
                    % Send the data
                    if strcmp(band, 'low')
                        write(app.device, dataToSendPaddedLow, "string");
                        disp(dataToSendLow);
                    elseif strcmp(band, 'mid')
                        write(app.device, dataToSendPaddedMid, "string");
                        disp(dataToSendMid);
                    elseif strcmp(band, 'high')
                        write(app.device, dataToSendPaddedHigh, "string");
                        disp(dataToSendHigh);
                    elseif strcmp(band, 'reset')
                        %write(app.device, app.LowCoeff_default, "string");
                        %write(app.device, app.MidCoeff_default, "string");
                        %write(app.device, app.HighCoeff_default, "string");
                        write(app.device, "Reset----------------------------------------------------------------------", "string");
                        disp("Reset!")
                        disp(dataToSendLow);
                        disp(dataToSendMid);
                        disp(dataToSendHigh);
                    else
                        disp('Error: Invalid band selected.');
                    end
                else
                    disp('Error: Serial port is not valid.');
                end
            catch ME
                disp('Error sending data to STM32.');
                disp(ME.message); % Display the actual error message
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.numPoints = 1000; % Set the number of points to a valid value
            app.frequencies = logspace(log10(20), log10(20000), app.numPoints); % Initialize frequencies
        
            % Initialize magnitudeResponse properties with zeros
            app.magnitudeResponse1 = zeros(1, app.numPoints);
            app.magnitudeResponse2 = zeros(1, app.numPoints);
            app.magnitudeResponse3 = zeros(1, app.numPoints);
            
            updateEqualizer(app); % Call the updateEqualizer function
            SendValuesToSTM32(app, 'reset'); % Initialise sending parameters
        end

        % Value changing function: QLowKnob
        function QLowKnobValueChanging(app, event)
            changingValue = event.Value;
            % app.GainLowText.Value = app.GainLowText.Value + string(changingValue) + ' ';
            disp('Q Low : ' + string(changingValue)); 
        end

        % Value changing function: QMidKnob
        function QMidKnobValueChanging(app, event)
            changingValue = event.Value;
            disp('Q Mid : ' + string(changingValue));
        end

        % Value changing function: QHighKnob
        function QHighKnobValueChanging(app, event)
            changingValue = event.Value;
            disp('Q High : ' + string(changingValue));
        end

        % Value changing function: GainLowSlider
        function GainLowSliderValueChanging(app, event)
            changingValue = event.Value;
            disp('Gain Low : ' + string(changingValue));
            app.GainLowText.Value = string(changingValue);
        end

        % Value changing function: GainMidSlider
        function GainMidSliderValueChanging(app, event)
            changingValue = event.Value;
            disp('Gain Mid : ' + string(changingValue));
            app.GainMidText.Value = string(changingValue);
        end

        % Value changing function: GainHighSlider
        function GainHighSliderValueChanging(app, event)
            changingValue = event.Value;
            disp('Gain High : ' + string(changingValue));
            app.GainHighText.Value = string(changingValue);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.QLowKnob.Value = 0.1;
            app.QMidKnob.Value = 0.1;
            app.QHighKnob.Value = 0.1;
            app.GainLowSlider.Value = 0.0;
            app.GainMidSlider.Value = 0.0;
            app.GainHighSlider.Value = 0.0;
            app.GainLowText.Value = "0.0";
            app.GainMidText.Value = "0.0";
            app.GainHighText.Value = "0.0";
    
            % Update the equalizer based on the new values
            updateEqualizer(app);
            SendValuesToSTM32(app, 'reset');
        end

        % Value changed function: QLowKnob
        function QLowKnobValueChanged(app, event)
            value = app.QLowKnob.Value;
            updateEqualizer(app);
            SendValuesToSTM32(app, 'low');
        end

        % Value changed function: QMidKnob
        function QMidKnobValueChanged(app, event)
            value = app.QMidKnob.Value;
            updateEqualizer(app);
            SendValuesToSTM32(app, 'mid');
        end

        % Value changed function: QHighKnob
        function QHighKnobValueChanged(app, event)
            value = app.QHighKnob.Value;
            updateEqualizer(app);
            SendValuesToSTM32(app, 'high');
        end

        % Value changed function: GainLowSlider
        function GainLowSliderValueChanged(app, event)
            value = app.GainLowSlider.Value;
            updateEqualizer(app);
            SendValuesToSTM32(app, 'low');
        end

        % Value changed function: GainMidSlider
        function GainMidSliderValueChanged(app, event)
            value = app.GainMidSlider.Value;
            updateEqualizer(app);
            SendValuesToSTM32(app, 'mid');
        end

        % Value changed function: GainHighSlider
        function GainHighSliderValueChanged(app, event)
            value = app.GainHighSlider.Value;
            updateEqualizer(app);
            SendValuesToSTM32(app, 'high');
        end

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
            % Close the main figure to terminate the app
            if isvalid(app.device) && strcmp(app.device.Status, 'open')
                delete(app.device); % Close and delete the serial port object
                disp('Serial port closed successfully.');
                delete(app.UIFigure);
            else
                disp('Serial port is not open or is invalid.');
            end
        end

        % Value changed function: GainLowText
        function GainLowTextValueChanged(app, event)
            value = app.GainLowText.Value;
            app.GainLowSlider.Value = str2double(value);
            updateEqualizer(app);
            SendValuesToSTM32(app, 'low');
        end

        % Value changed function: GainMidText
        function GainMidTextValueChanged(app, event)
            value = app.GainMidText.Value;
            app.GainMidSlider.Value = str2double(value);
            updateEqualizer(app);
            SendValuesToSTM32(app, 'mid');
        end

        % Value changed function: GainHighText
        function GainHighTextValueChanged(app, event)
            value = app.GainHighText.Value;
            app.GainHighSlider.Value = str2double(value);
            updateEqualizer(app);
            SendValuesToSTM32(app, 'high');
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {370, 370};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {387, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.QLowKnobLabel = uilabel(app.LeftPanel);
            app.QLowKnobLabel.Text = 'Q Low';
            app.QLowKnob = uiknob(app.LeftPanel, 'continuous');
            app.QLowKnob.Limits = [0.1 3];
            app.QLowKnob.MajorTicks = [0.1 0.4 0.6 0.8 1 1.5 2 2.5 3];
            app.QLowKnob.ValueChangedFcn = createCallbackFcn(app, @QLowKnobValueChanged, true);
            app.QLowKnob.ValueChangingFcn = createCallbackFcn(app, @QLowKnobValueChanging, true);
            app.QLowKnob.MinorTicks = [0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1 1.1 1.2 1.3 1.4 1.5 1.6 1.7 1.8 1.9 2 2.1 2.2 2.3 2.4 2.5 2.6 2.7 2.8 2.9 3];
            app.QLowKnob.Value = 1;
            app.QMidKnobLabel = uilabel(app.LeftPanel);
            app.QMidKnobLabel.Text = 'Q Mid';
            app.QMidKnob = uiknob(app.LeftPanel, 'continuous');
            app.QMidKnob.Limits = [0.1 3];
            app.QMidKnob.MajorTicks = [0.1 0.4 0.6 0.8 1 1.5 2 2.5 3];
            app.QMidKnob.ValueChangedFcn = createCallbackFcn(app, @QMidKnobValueChanged, true);
            app.QMidKnob.ValueChangingFcn = createCallbackFcn(app, @QMidKnobValueChanging, true);
            app.QMidKnob.Value = 1;
            app.QHighKnobLabel = uilabel(app.LeftPanel);
            app.QHighKnobLabel.Text = 'Q High';
            app.QHighKnob = uiknob(app.LeftPanel, 'continuous');
            app.QHighKnob.Limits = [0.1 3];
            app.QHighKnob.MajorTicks = [0.1 0.4 0.6 0.8 1 1.5 2 2.5 3];
            app.QHighKnob.ValueChangedFcn = createCallbackFcn(app, @QHighKnobValueChanged, true);
            app.QHighKnob.ValueChangingFcn = createCallbackFcn(app, @QHighKnobValueChanging, true);
            app.QHighKnob.Value = 1;
            app.GainLowSliderLabel = uilabel(app.LeftPanel);
            app.GainLowSliderLabel.Text = 'Gain Low';
            app.GainLowSlider = uislider(app.LeftPanel);
            app.GainLowSlider.Limits = [-20 20];
            app.GainLowSlider.ValueChangedFcn = createCallbackFcn(app, @GainLowSliderValueChanged, true);
            app.GainLowSlider.ValueChangingFcn = createCallbackFcn(app, @GainLowSliderValueChanging, true);
            app.GainMidSliderLabel = uilabel(app.LeftPanel);
            app.GainMidSliderLabel.Text = 'Gain Mid';
            app.GainMidSlider = uislider(app.LeftPanel);
            app.GainMidSlider.Limits = [-20 20];
            app.GainMidSlider.ValueChangedFcn = createCallbackFcn(app, @GainMidSliderValueChanged, true);
            app.GainMidSlider.ValueChangingFcn = createCallbackFcn(app, @GainMidSliderValueChanging, true);
            app.GainHighSliderLabel = uilabel(app.LeftPanel);
            app.GainHighSliderLabel.Text = 'Gain High';
            app.GainHighSlider = uislider(app.LeftPanel);
            app.GainHighSlider.Limits = [-20 20];
            app.GainHighSlider.ValueChangedFcn = createCallbackFcn(app, @GainHighSliderValueChanged, true);
            app.GainHighSlider.ValueChangingFcn = createCallbackFcn(app, @GainHighSliderValueChanging, true);
            app.ResetButton = uibutton(app.LeftPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.QuitButton = uibutton(app.LeftPanel, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
            app.GainLowText = uieditfield(app.LeftPanel, 'text');
            app.GainLowText.ValueChangedFcn = createCallbackFcn(app, @GainLowTextValueChanged, true);
            app.GainLowText.Value = '0.0';
            app.GainMidText = uieditfield(app.LeftPanel, 'text');
            app.GainMidText.ValueChangedFcn = createCallbackFcn(app, @GainMidTextValueChanged, true);
            app.GainMidText.Value = '0.0';
            app.GainHighText = uieditfield(app.LeftPanel, 'text');
            app.GainHighText.ValueChangedFcn = createCallbackFcn(app, @GainHighTextValueChanged, true);
            app.GainHighText.Value = '0.0';
            app.LowMidHighMidarenotincludedinthisdemoLabel = uilabel(app.LeftPanel);
            app.LowMidHighMidarenotincludedinthisdemoLabel.Text = 'Low Mid, High Mid are not included in this demo';
            app.EQMAT5Label = uilabel(app.LeftPanel);
            app.EQMAT5Label.Text = 'EQMAT5';
            app.BandEqualizerusingSTM32Label = uilabel(app.LeftPanel);
            app.BandEqualizerusingSTM32Label.Text = {'5-Band Equalizer'; 'using STM32'};
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Bode Plot')
            xlabel(app.UIAxes, 'Frequency (Hz)')
            ylabel(app.UIAxes, 'Magnitude (dB)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 10000];
            app.UIAxes.XScale = 'log';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = testgui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end